% description: the user path. SPM12 is included as part of this package. It can be obtained externally at https://www.fil.ion.ucl.ac.uk/spm/software/spm12/
% This software is released under the GNU General Public License Version 3.
% John C. Williams, Philip N. Tubiolo, Jacob R. Luceno, Jared X. Van Snellenberg,
% Advancing motion denoising of multiband resting-state functional connectivity fMRI data,
% NeuroImage,
% Volume 249,
% 2022,
% 118907,
% ISSN 1053-8119,
% PMID:35033673,
% https://doi.org/10.1016/j.neuroimage.2022.118907.
% (https://www.sciencedirect.com/science/article/pii/S1053811922000374)
% Abstract: Simultaneous multi-slice (multiband) accelerated functional magnetic resonance imaging (fMRI) provides dramatically improved temporal and spatial resolution for resting-state functional
% connectivity (RSFC) studies of the human brain in health and disease. However, multiband acceleration also poses unique challenges for denoising of subject motion induced data artifacts, the presence
% of which is a major confound in RSFC research that substantively diminishes reliability and reproducibility. We comprehensively evaluated existing and novel approaches to volume censoring-based motion
% denoising in the Human Connectome Project (HCP) dataset. We show that assumptions underlying common metrics for evaluating motion denoising pipelines, especially those based on quality control-
% functional connectivity (QC-FC) correlations and differences between high- and low-motion participants, are problematic, and appear to be inappropriate in their current widespread use as indicators of
% comparative pipeline performance and as targets for investigators to use when tuning pipelines for their own datasets. We further develop two new quantitative metrics that are instead agnostic to QC-
% FC correlations and other measures that rely upon the null assumption that no true relationships exist between trait measures of subject motion and functional connectivity, and demonstrate their use
% as benchmarks for comparing volume censoring methods. Finally, we develop and validate quantitative methods for determining dataset-specific optimal volume censoring parameters prior to the final
% analysis of a dataset, and provide straightforward recommendations and code for all investigators to apply this optimized approach to their own RSFC datasets.
% Keywords: Functional MRI; Volume censoring; Multiband fMRI; Resting state functional connectivity; Participant motion; Artifact removal
classdef ThresholdOptimizer_AlphaUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HCPTab                         matlab.ui.container.Tab
        RestingStateRunNamesLabel      matlab.ui.control.Label
        HCPRunNamescsvEditField        matlab.ui.control.EditField
        HCPSourceDirectoryButton       matlab.ui.control.Button
        HCPSrcNoPathChosenLabel        matlab.ui.control.Label
        fMRIPrepTab                    matlab.ui.container.Tab
        TaskNameLabel                  matlab.ui.control.Label
        PrepTaskNamesEditField         matlab.ui.control.EditField
        PrepSourceDIrectoryButton      matlab.ui.control.Button
        PrepNoPathChosenLabel          matlab.ui.control.Label
        FunctionalImageSpaceEditFieldLabel  matlab.ui.control.Label
        FunctionalImageSpaceEditField  matlab.ui.control.EditField
        CustomTab                      matlab.ui.container.Tab
        CustomRestingStateRunNamesButton  matlab.ui.control.Button
        CustomNoRunFileChosenLabel     matlab.ui.control.Label
        CustomMaskNamesButton          matlab.ui.control.Button
        CustomNoMaskFileChosenLabel    matlab.ui.control.Label
        CustomMotionParametersButton   matlab.ui.control.Button
        CustomNoMPFileChosenLabel      matlab.ui.control.Label
        OutputDirectoryButton          matlab.ui.control.Button
        WorkingNoPathChosenLabel       matlab.ui.control.Label
        TRsEditFieldLabel              matlab.ui.control.Label
        TRsEditField                   matlab.ui.control.NumericEditField
        VolumestoTrimEditFieldLabel_2  matlab.ui.control.Label
        VolumestoTrimEditField         matlab.ui.control.NumericEditField
        UseGSRButtonGroup              matlab.ui.container.ButtonGroup
        YesButton                      matlab.ui.control.RadioButton
        NoButton                       matlab.ui.control.RadioButton
        RuninGUIButton                 matlab.ui.control.Button
        GenerateandDisplayCodeButton   matlab.ui.control.Button
        WelcometotheCNAPLabOptimizationToolLabel  matlab.ui.control.Label
        ContinueFromCrashCheckBox      matlab.ui.control.CheckBox
        CodeViewTextAreaLabel          matlab.ui.control.Label
        CodeViewTextArea               matlab.ui.control.TextArea
        ClearSelectionsandResetGUIButton  matlab.ui.control.Button
        InProgressLabel                matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        PleasechoosesettingsrelevanttoyourpreprocessingpipelineLabel  matlab.ui.control.Label
        BandPassFilteringParametersPanel  matlab.ui.container.Panel
        dBCutoffFrequenciesLabel       matlab.ui.control.Label
        LowHzEditFieldLabel            matlab.ui.control.Label
        LowHzEditField                 matlab.ui.control.NumericEditField
        HighHzEditFieldLabel           matlab.ui.control.Label
        HighHzEditField                matlab.ui.control.NumericEditField
        SecondstoTrimPostFilteringLabel  matlab.ui.control.Label
        SecondstoTrimPostFilteringEditField  matlab.ui.control.NumericEditField
        UseDefaultFilteringParametersCheckBox  matlab.ui.control.CheckBox
        Label                          matlab.ui.control.Label
        MinimumRunLengthsEditFieldLabel  matlab.ui.control.Label
        MinimumRunLengthsEditField     matlab.ui.control.NumericEditField
        UseDefaultCheckBox             matlab.ui.control.CheckBox
    end


    properties (Access = private)
        useGSR
        continueFlag
        workingPath
        HCPpath
        HCPrunNames
        PrepPath
        PrepTaskName
        TR
        nTrim
        customRSrunsFile
        maskMatrixFile
        MPfile
        format
        updateButtonFlag
        oldReleaseFlag
        defaultFilter
        lowHz
        highHz
        secTrimPostBpf_app
        minimumSecondsDataPerRun_app
        filterCutoffs_app
        defaultMinSecFlag
        imageSpace
    end

    methods (Access = private)

        function confirmRun(app,src,event)
            if isempty(app.workingPath)
                uialert(app.UIFigure,'Please choose a working directory!','Run Failed');
                return
            end
            try
                if event.SelectedOptionIndex ==1
                    if ~app.continueFlag
                        if (~isempty(app.HCPpath))||(~isempty(app.PrepPath))
                            if ~isempty(app.HCPpath)
                                app.format = 'HCP';
                            else
                                app.format = 'fMRIPrep';
                            end
                        elseif (isempty(app.HCPpath))&&(isempty(app.PrepPath))
                            app.format = 'custom';
                        else
                            error('Error Choosing Pipeline')
                        end

                        if app.defaultFilter %load default filter if need be
                            load('mcotDefaults.mat','filterCutoffs','numOfSecToTrim');
                            app.filterCutoffs_app = filterCutoffs;
                            app.lowHz = filterCutoffs(1);
                            app.highHz = filterCutoffs(2);
                            app.secTrimPostBpf_app = numOfSecToTrim;
                        else
                            app.filterCutoffs_app = [app.lowHz app.highHz];
                        end

                        if app.defaultMinSecFlag
                            load('mcotDefaults.mat','minSecDataNeeded');
                            app.minimumSecondsDataPerRun_app = minSecDataNeeded;
                        end

                        if strcmpi(app.format,'HCP')
                            app.RuninGUIButton.Visible = 'off';
                            app.InProgressLabel.Visible = 'on';
                            mCotWrapper(app.workingPath, 'sourcedirectory',app.HCPpath, 'format', 'HCP', 'tr', app.TR, 'useGSR',logical(app.useGSR), 'nTrim', app.nTrim,'runNames',app.HCPrunNames, 'minimumSecondsDataPerRun',app.minimumSecondsDataPerRun_app, 'filterCutoffs',app.filterCutoffs_app, 'secTrimPostBpf', app.secTrimPostBpf_app);
                            app.RuninGUIButton.Visible = 'on';
                            app.InProgressLabel.Visible = 'off';
                        elseif strcmpi(app.format,'fMRIPrep')
                            app.RuninGUIButton.Visible = 'off';
                            app.InProgressLabel.Visible = 'on';
                            mCotWrapper(app.workingPath, 'sourcedirectory',app.PrepPath, 'format', 'fMRIPrep', 'tr', app.TR, 'useGSR',logical(app.useGSR), 'nTrim', app.nTrim,'runNames',app.PrepTaskName, 'minimumSecondsDataPerRun',app.minimumSecondsDataPerRun_app, 'filterCutoffs',app.filterCutoffs_app, 'secTrimPostBpf', app.secTrimPostBpf_app,'imageSpace',app.imageSpace);
                            app.RuninGUIButton.Visible = 'on';
                            app.InProgressLabel.Visible = 'off';
                        elseif strcmpi(app.format,'custom')
                            app.RuninGUIButton.Visible = 'off';
                            app.InProgressLabel.Visible = 'on';
                            load(app.customRSrunsFile,'filenamematrix');
                            load(app.maskMatrixFile,'maskmatrix');
                            load(app.MPfile,'motionparameters');
                            mCotWrapper(app.workingPath,'format','custom','filenamematrix',filenamematrix,'maskmatrix',maskmatrix,'motionparameters',motionparameters,'tr',app.TR,'useGSR',logical(app.useGSR),'nTrim',app.nTrim, 'minimumSecondsDataPerRun',app.minimumSecondsDataPerRun_app, 'filterCutoffs',app.filterCutoffs_app, 'secTrimPostBpf', app.secTrimPostBpf_app);
                            app.RuninGUIButton.Visible = 'on';
                            app.InProgressLabel.Visible = 'off';
                        end
                    else
                        mCotWrapper(app.workingPath,'continue');
                    end
                end
            catch err
                errMessage = getReport(err,'extended','hyperlinks','off');
                uialert(app.UIFigure,errMessage,'Run Failed for the Following Reason:')
                app.InProgressLabel.Visible = 'off';
                app.RuninGUIButton.Visible = 'on';
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if app.NoButton.Value == 1
                app.useGSR = false;
            else
                app.useGSR = true;
            end
            app.continueFlag = app.ContinueFromCrashCheckBox.Value;
            app.TR = app.TRsEditField.Value;
            app.nTrim = app.VolumestoTrimEditField.Value;
            app.updateButtonFlag = 0;
            try
                app.oldReleaseFlag = isMATLABReleaseOlderThan("R2020b");
            catch
                app.oldReleaseFlag = 1;
            end
            load('mcotDefaults.mat');
            app.HighHzEditField.Value = filterCutoffs(2);
            app.LowHzEditField.Value = filterCutoffs(1);
            app.SecondstoTrimPostFilteringEditField.Value = numOfSecToTrim;
            app.MinimumRunLengthsEditField.Value = minSecDataNeeded;
            app.lowHz = app.LowHzEditField.Value;
            app.highHz = app.HighHzEditField.Value;
            app.secTrimPostBpf_app = app.SecondstoTrimPostFilteringEditField.Value;
            app.minimumSecondsDataPerRun_app = app.MinimumRunLengthsEditField.Value;
        end

        % Button pushed function: OutputDirectoryButton
        function OutputDirectoryButtonPushed(app, event)
            app.workingPath = uigetdir();
            app.WorkingNoPathChosenLabel.Text = string(app.workingPath);
        end

        % Button pushed function: HCPSourceDirectoryButton
        function HCPSourceDirectoryButtonPushed(app, event)
            app.HCPpath = uigetdir();
            app.HCPSrcNoPathChosenLabel.Text = string(app.HCPpath);
        end

        % Value changed function: HCPRunNamescsvEditField
        function HCPRunNamescsvEditFieldValueChanged(app, event)
            app.HCPrunNames = app.HCPRunNamescsvEditField.Value;
            tempcell = strsplit(app.HCPrunNames,',');
            tempcellNoWS = cellfun(@strtrim,tempcell,'UniformOutput',false);
            app.HCPrunNames = tempcellNoWS;
        end

        % Button pushed function: PrepSourceDIrectoryButton
        function PrepSourceDIrectoryButtonPushed(app, event)
            app.PrepPath = uigetdir();
            app.PrepNoPathChosenLabel.Text = string(app.PrepPath);
        end

        % Value changed function: PrepTaskNamesEditField
        function PrepTaskNamesEditFieldValueChanged(app, event)
            app.PrepTaskName = app.PrepTaskNamesEditField.Value;
        end

        % Value changed function: TRsEditField
        function TRsEditFieldValueChanged(app, event)
            app.TR = app.TRsEditField.Value;
        end

        % Value changed function: VolumestoTrimEditField
        function VolumestoTrimEditFieldValueChanged(app, event)
            app.nTrim = app.VolumestoTrimEditField.Value;
        end

        % Selection changed function: UseGSRButtonGroup
        function UseGSRButtonGroupSelectionChanged(app, event)
            if app.NoButton.Value == 1
                app.useGSR = false;
            else
                app.useGSR = true;
            end
        end

        % Value changed function: ContinueFromCrashCheckBox
        function ContinueFromCrashCheckBoxValueChanged(app, event)
            app.continueFlag = app.ContinueFromCrashCheckBox.Value;
            if app.continueFlag && ~app.oldReleaseFlag
                app.UseGSRButtonGroup.Enable = 'off';
                app.VolumestoTrimEditField.Enable = 'off';
                app.TRsEditField.Enable = 'off';
                app.HCPSourceDirectoryButton.Enable = 'off';
                app.HCPRunNamescsvEditField.Enable = 'off';
                app.PrepSourceDIrectoryButton.Enable = 'off';
                app.PrepTaskNamesEditField.Enable = 'off';
                app.CustomMotionParametersButton.Enable = 'off';
                app.CustomMaskNamesButton.Enable = 'off';
                app.CustomRestingStateRunNamesButton.Enable = 'off';
                app.BandPassFilteringParametersPanel.Enable = 'off';
                app.MinimumRunLengthsEditField.Enable = 'off';
                app.UseDefaultCheckBox.Enable = 'off';
                app.FunctionalImageSpaceEditField.Enable = 'off';
            elseif ~app.continueFlag && ~app.oldReleaseFlag
                app.UseGSRButtonGroup.Enable = 'on';
                app.VolumestoTrimEditField.Enable = 'on';
                app.TRsEditField.Enable = 'on';
                app.HCPSourceDirectoryButton.Enable = 'on';
                app.HCPRunNamescsvEditField.Enable = 'on';
                app.PrepSourceDIrectoryButton.Enable = 'on';
                app.PrepTaskNamesEditField.Enable = 'on';
                app.CustomMotionParametersButton.Enable = 'on';
                app.CustomMaskNamesButton.Enable = 'on';
                app.CustomRestingStateRunNamesButton.Enable = 'on';
                app.BandPassFilteringParametersPanel.Enable = 'on';
                app.MinimumRunLengthsEditField.Enable = 'on';
                app.UseDefaultCheckBox.Enable = 'on';
                app.FunctionalImageSpaceEditField.Enable = 'on';
            elseif app.continueFlag && app.oldReleaseFlag
                app.UseGSRButtonGroup.Visible = 'off';
                app.VolumestoTrimEditField.Visible = 'off';
                app.TRsEditField.Visible = 'off';
                app.HCPSourceDirectoryButton.Visible = 'off';
                app.HCPRunNamescsvEditField.Visible = 'off';
                app.PrepSourceDIrectoryButton.Visible = 'off';
                app.PrepTaskNamesEditField.Visible = 'off';
                app.CustomMotionParametersButton.Visible = 'off';
                app.CustomMaskNamesButton.Visible = 'off';
                app.CustomRestingStateRunNamesButton.Visible = 'off';
                app.BandPassFilteringParametersPanel.Visible = 'off';
                app.MinimumRunLengthsEditField.Visible = 'off';
                app.UseDefaultCheckBox.Visible = 'off';
                app.FunctionalImageSpaceEditField.Visible = 'off';
            else
                app.UseGSRButtonGroup.Visible = 'on';
                app.VolumestoTrimEditField.Visible = 'on';
                app.TRsEditField.Visible = 'on';
                app.HCPSourceDirectoryButton.Visible = 'on';
                app.HCPRunNamescsvEditField.Visible = 'on';
                app.PrepSourceDIrectoryButton.Visible = 'on';
                app.PrepTaskNamesEditField.Visible = 'on';
                app.CustomMotionParametersButton.Visible = 'on';
                app.CustomMaskNamesButton.Visible = 'on';
                app.CustomRestingStateRunNamesButton.Visible = 'on';
                app.BandPassFilteringParametersPanel.Visible = 'on';
                app.MinimumRunLengthsEditField.Visible = 'on';
                app.UseDefaultCheckBox.Visible = 'on';
                app.FunctionalImageSpaceEditField.Visible = 'on';
            end
        end

        % Button pushed function: CustomRestingStateRunNamesButton
        function CustomRestingStateRunNamesButtonPushed(app, event)
            app.customRSrunsFile = uigetfile('*.mat');
            app.CustomNoRunFileChosenLabel.Text = string(app.customRSrunsFile);
        end

        % Button pushed function: CustomMaskNamesButton
        function CustomMaskNamesButtonPushed(app, event)
            app.maskMatrixFile = uigetfile('*.mat');
            app.CustomNoMaskFileChosenLabel.Text = string(app.maskMatrixFile);
        end

        % Button pushed function: CustomMotionParametersButton
        function CustomMotionParametersButtonPushed(app, event)
            app.MPfile = uigetfile('*.mat');
            app.CustomNoMPFileChosenLabel.Text = string(app.MPfile);
        end

        % Button pushed function: GenerateandDisplayCodeButton
        function GenerateandDisplayCodeButtonPushed(app, event)
            app.GenerateandDisplayCodeButton.Text = 'Update Code';
            % decide which pipeline they chose
            if ~app.continueFlag
                if (~isempty(app.HCPpath))||(~isempty(app.PrepPath))
                    if ~isempty(app.HCPpath)
                        app.format = 'HCP';
                    else
                        app.format = 'fMRIPrep';
                    end
                elseif (isempty(app.HCPpath))&&(isempty(app.PrepPath))
                    app.format = 'Custom';
                else
                    error('Error Choosing Pipeline')
                end
                % take care of loading some defaults for the filter
                if app.defaultFilter
                    load('mcotDefaults.mat','filterCutoffs','numOfSecToTrim');
                    app.filterCutoffs_app = filterCutoffs;
                    app.lowHz = filterCutoffs(1);
                    app.highHz = filterCutoffs(2);
                    app.secTrimPostBpf_app = numOfSecToTrim;
                else
                    app.filterCutoffs_app = [app.lowHz app.highHz];
                end
                if app.defaultMinSecFlag
                    load('mcotDefaults.mat','minSecDataNeeded');
                    app.minimumSecondsDataPerRun_app = minSecDataNeeded;
                end

                if strcmp(app.format,'HCP')
                    runstr = '';
                    if isempty(app.HCPrunNames)
                        uialert(app.UIFigure,'Please specify run identifiers!','HCP Error')
                        return
                    end
                    for i = 1:length(app.HCPrunNames)
                        if i == 1
                            runstr = ["'%s'"];
                        else
                            runstr = [runstr ',' "'%s'"];
                        end

                    end
                    runstr = strjoin(runstr);
                    tempTxt = ["mCotWrapper('%s', 'sourcedirectory','%s', 'format', '%s', 'TR', %.3f, 'useGSR',%s, 'nTrim', %.1f,'runNames',{" runstr "}, 'minimumSecondsDataPerRun', %.1f, 'filterCutoffs', [%.4f %.4f], 'secTrimPostBpf', %.1f)" ];
                    tempTxt = strjoin(tempTxt);
                    funcTxt = sprintf(tempTxt,app.workingPath,app.HCPpath,app.format,app.TR,string(app.useGSR),app.nTrim,app.HCPrunNames{:},app.minimumSecondsDataPerRun_app,app.filterCutoffs_app(1),app.filterCutoffs_app(2),app.secTrimPostBpf_app);
                elseif strcmp(app.format,'fMRIPrep')

                    funcTxt = sprintf("mCotWrapper('%s', 'sourcedirectory','%s' 'format', '%s', 'TR', %.3f, 'useGSR',%s, 'nTrim', %.1f,'runNames','%s', 'minimumSecondsDataPerRun', %.1f, 'filterCutoffs', [%.4f %.4f], 'secTrimPostBpf', %.1f, 'imageSpace', '%s')",app.workingPath,app.PrepPath,app.format,app.TR,string(app.useGSR),app.nTrim,string(app.PrepTaskName),app.minimumSecondsDataPerRun_app,app.filterCutoffs_app(1),app.filterCutoffs_app(2),app.secTrimPostBpf_app,app.imageSpace);

                elseif strcmp(app.format,'Custom')

                    funcTxt = sprintf("load('%s','filenamematrix');\nload('%s','maskmatrix');\nload('%s','motionparameters');\nmCotWrapper('%s','format','Custom','filenamematrix',filenamematrix,'maskmatrix',maskmatrix,'motionparameters',motionparameters,'TR','%.3f','useGSR',%s,'nTrim',%.1f, 'minimumSecondsDataPerRun', %.1f, 'filterCutoffs', [%.4f %.4f], 'secTrimPostBpf', %.1f)",app.customRSrunsFile,app.maskMatrixFile,app.MPfile,app.workingPath,app.TR,string(app.useGSR),app.nTrim,app.minimumSecondsDataPerRun_app,app.filterCutoffs_app(1),app.filterCutoffs_app(2),app.secTrimPostBpf_app);

                end
            else
                funcTxt = sprintf("mCotWrapper('%s','continue')",app.workingPath);
            end
            app.CodeViewTextArea.Value = funcTxt;
            app.updateButtonFlag = 1;
        end

        % Button pushed function: RuninGUIButton
        function RuninGUIButtonPushed(app, event)
            uiconfirm(app.UIFigure,'Are you sure you want to run? This may take a very long time.','Confirm Run?','Icon','warning',...
                'CloseFcn',@(src,event)confirmRun(app,src,event));
        end

        % Button pushed function: ClearSelectionsandResetGUIButton
        function ClearSelectionsandResetGUIButtonPushed(app, event)
            close(app.UIFigure);
            ThresholdOptimizer_AlphaUI;
        end

        % Value changed function: LowHzEditField
        function LowHzEditFieldValueChanged(app, event)
            app.lowHz = app.LowHzEditField.Value;
        end

        % Value changed function: HighHzEditField
        function HighHzEditFieldValueChanged(app, event)
            app.highHz = app.HighHzEditField.Value;
        end

        % Value changed function: 
        % SecondstoTrimPostFilteringEditField
        function SecondstoTrimPostFilteringEditFieldValueChanged(app, event)
            app.secTrimPostBpf_app = app.SecondstoTrimPostFilteringEditField.Value;
        end

        % Value changed function: 
        % UseDefaultFilteringParametersCheckBox
        function UseDefaultFilteringParametersCheckBoxValueChanged(app, event)
            app.defaultFilter = app.UseDefaultFilteringParametersCheckBox.Value;
            if app.defaultFilter && ~app.oldReleaseFlag
                app.LowHzEditField.Enable = 'off';
                app.HighHzEditField.Enable = 'off';
                app.SecondstoTrimPostFilteringEditField.Enable = 'off';
            elseif app.defaultFilter && app.oldReleaseFlag
                app.LowHzEditField.Visible = 'off';
                app.HighHzEditField.Visible = 'off';
                app.SecondstoTrimPostFilteringEditField.Visible = 'off';
            elseif ~app.defaultFilter && app.oldReleaseFlag
                app.LowHzEditField.Visible = 'on';
                app.HighHzEditField.Visible = 'on';
                app.SecondstoTrimPostFilteringEditField.Visible = 'on';
                app.lowHz = app.LowHzEditField.Value;
                app.highHz = app.HighHzEditField.Value;
                app.secTrimPostBpf_app = app.SecondstoTrimPostFilteringEditField.Value;
            else
                app.LowHzEditField.Enable = 'on';
                app.HighHzEditField.Enable = 'on';
                app.SecondstoTrimPostFilteringEditField.Enable = 'on';
                app.lowHz = app.LowHzEditField.Value;
                app.highHz = app.HighHzEditField.Value;
                app.secTrimPostBpf_app = app.SecondstoTrimPostFilteringEditField.Value;
            end
        end

        % Value changed function: MinimumRunLengthsEditField
        function MinimumRunLengthsEditFieldValueChanged(app, event)
            app.minimumSecondsDataPerRun_app = app.MinimumRunLengthsEditField.Value;
        end

        % Value changed function: UseDefaultCheckBox
        function UseDefaultCheckBoxValueChanged(app, event)
            app.defaultMinSecFlag = app.UseDefaultCheckBox.Value;
            if app.defaultMinSecFlag && ~app.oldReleaseFlag
                app.MinimumRunLengthsEditField.Enable = 'off';
            elseif app.defaultMinSecFlag && app.oldReleaseFlag
                app.MinimumRunLengthsEditField.Visible = 'off';
            elseif ~app.defaultMinSecFlag && app.oldReleaseFlag
                app.MinimumRunLengthsEditField.Visible = 'on';
                app.minimumSecondsDataPerRun_app = app.MinimumRunLengthsEditField.Value;
            else
                app.MinimumRunLengthsEditField.Enable = 'on';
                app.minimumSecondsDataPerRun_app = app.MinimumRunLengthsEditField.Value;
            end

        end

        % Value changed function: FunctionalImageSpaceEditField
        function FunctionalImageSpaceEditFieldValueChanged(app, event)
            app.imageSpace = string(app.FunctionalImageSpaceEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Tooltip = {''};
            app.HCPTab = uitab(app.TabGroup);
            app.HCPTab.Tooltip = {'Enter information here if the Human Connectome Project Minimal Preprocessing Pipeline was used.'};
            app.HCPTab.Title = 'HCP';
            app.RestingStateRunNamesLabel = uilabel(app.HCPTab);
            app.RestingStateRunNamesLabel.Tooltip = {'Specifiy the run identifiers in the study, entered in a comma-delimited list. For example, if your study contains 4 runs with the file name suffixes ''_rs1'', ''_rs2'', ''_rs3'', and ''_rs4'', they should be entered as:'; ''; '_rs1, _rs2, _rs3, _rs4'};
            app.RestingStateRunNamesLabel.Text = 'Resting-State Run Names ';
            app.HCPRunNamescsvEditField = uieditfield(app.HCPTab, 'text');
            app.HCPRunNamescsvEditField.ValueChangedFcn = createCallbackFcn(app, @HCPRunNamescsvEditFieldValueChanged, true);
            app.HCPRunNamescsvEditField.Tooltip = {'Specifiy the run identifiers in the study, entered in a comma-delimited list. For example, if your study contains 4 runs with the file name suffixes ''_rs1'', ''_rs2'', ''_rs3'', and ''_rs4'', they should be entered as:'; ''; '_rs1, _rs2, _rs3, _rs4'};
            app.HCPSourceDirectoryButton = uibutton(app.HCPTab, 'push');
            app.HCPSourceDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @HCPSourceDirectoryButtonPushed, true);
            app.HCPSourceDirectoryButton.Tooltip = {'Directory that contains HCP preprocessed data to be used during optimization. This should be the direct output directory of the HCP Minimal Preprocessing Pipeline.'};
            app.HCPSourceDirectoryButton.Text = 'Source Directory';
            app.HCPSrcNoPathChosenLabel = uilabel(app.HCPTab);
            app.HCPSrcNoPathChosenLabel.Text = 'No Path Chosen';
            app.fMRIPrepTab = uitab(app.TabGroup);
            app.fMRIPrepTab.Tooltip = {'Enter information here if fMRIPrep was used.'};
            app.fMRIPrepTab.Title = 'fMRIPrep';
            app.TaskNameLabel = uilabel(app.fMRIPrepTab);
            app.TaskNameLabel.Tooltip = {'Specify the task identifier in the study as it was used during fMRIPrep preprocessing. For example, for a study with the resting-state task name RSFC, the Task Name should be entered as:'; ''; 'RSFC'};
            app.TaskNameLabel.Text = 'Task Name ';
            app.PrepTaskNamesEditField = uieditfield(app.fMRIPrepTab, 'text');
            app.PrepTaskNamesEditField.ValueChangedFcn = createCallbackFcn(app, @PrepTaskNamesEditFieldValueChanged, true);
            app.PrepTaskNamesEditField.Tooltip = {'Specify the task identifier in the study as it was used during fMRIPrep preprocessing. For example, for a study with the resting-state task name RSFC, the Task Name should be entered as:'; ''; 'RSFC'};
            app.PrepSourceDIrectoryButton = uibutton(app.fMRIPrepTab, 'push');
            app.PrepSourceDIrectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PrepSourceDIrectoryButtonPushed, true);
            app.PrepSourceDIrectoryButton.Tooltip = {'Directory that contains fMRIPrep preprocessed data to be used during optimization. This should be the direct output directory of fMRIPrep in BIDS format.'};
            app.PrepSourceDIrectoryButton.Text = 'Source Directory';
            app.PrepNoPathChosenLabel = uilabel(app.fMRIPrepTab);
            app.PrepNoPathChosenLabel.Text = 'No Path Chosen';
            app.FunctionalImageSpaceEditFieldLabel = uilabel(app.fMRIPrepTab);
            app.FunctionalImageSpaceEditFieldLabel.Tooltip = {'Specify the image space used for normalization during fMRIPrep preprocessing.'};
            app.FunctionalImageSpaceEditFieldLabel.Text = 'Functional Image Space';
            app.FunctionalImageSpaceEditField = uieditfield(app.fMRIPrepTab, 'text');
            app.FunctionalImageSpaceEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionalImageSpaceEditFieldValueChanged, true);
            app.FunctionalImageSpaceEditField.Tooltip = {'Specify the image space used for normalization during fMRIPrep preprocessing.'};
            app.CustomTab = uitab(app.TabGroup);
            app.CustomTab.Tooltip = {'Enter information here if a custom user-implemented preprocessing pipeline was used.'};
            app.CustomTab.Title = 'Custom';
            app.CustomRestingStateRunNamesButton = uibutton(app.CustomTab, 'push');
            app.CustomRestingStateRunNamesButton.ButtonPushedFcn = createCallbackFcn(app, @CustomRestingStateRunNamesButtonPushed, true);
            app.CustomRestingStateRunNamesButton.Tooltip = {'Specify a .mat file that contains a variable named ''filenamematrix.'' '; 'filenamematrix should be an N x M array where N is the number of subjects and M is the maximum number of runs per subject. Each entry is a full path to a resting-state fMRI run. Cells can be left empty if a subject is missing runs.'};
            app.CustomRestingStateRunNamesButton.Text = 'Resting-State Run Names';
            app.CustomNoRunFileChosenLabel = uilabel(app.CustomTab);
            app.CustomNoRunFileChosenLabel.Text = 'No Run File Chosen';
            app.CustomMaskNamesButton = uibutton(app.CustomTab, 'push');
            app.CustomMaskNamesButton.ButtonPushedFcn = createCallbackFcn(app, @CustomMaskNamesButtonPushed, true);
            app.CustomMaskNamesButton.Tooltip = {'Specify a .mat file containing a variable named ''maskmatrix.'''; 'maskmatrix should be an N x 3 cell array, where N is the number of subject. The first column is a full file path to the whole brain mask of each subject. The second column is a full file path to the white matter mask of each subject. The third column is a full file path to the cerebrospinal fluid mask of each subject.'};
            app.CustomMaskNamesButton.Text = 'Mask Names';
            app.CustomNoMaskFileChosenLabel = uilabel(app.CustomTab);
            app.CustomNoMaskFileChosenLabel.Text = 'No Mask File Chosen';
            app.CustomMotionParametersButton = uibutton(app.CustomTab, 'push');
            app.CustomMotionParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CustomMotionParametersButtonPushed, true);
            app.CustomMotionParametersButton.Tooltip = {'Specify a .mat file containing a variable named ''motionparameters.'' '; 'motionparameters should be an N x M cell array, where N is the number of subjects and M is the maximum number of runs per subejct. Each entry is a Y x 6 array, where Y is the number of volumes in each resting-state run and each column is a translational or rotational motion parameter. The order of these columns is: x-translation, y-translation, z-translation, roll, pitch, yaw.'};
            app.CustomMotionParametersButton.Text = 'Motion Parameters';
            app.CustomNoMPFileChosenLabel = uilabel(app.CustomTab);
            app.CustomNoMPFileChosenLabel.Text = 'No MP File Chosen';
            app.OutputDirectoryButton = uibutton(app.UIFigure, 'push');
            app.OutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OutputDirectoryButtonPushed, true);
            app.OutputDirectoryButton.Tooltip = {'Denotes directory in which to save all intermediate and final outputs.'};
            app.OutputDirectoryButton.Text = 'Output Directory';
            app.WorkingNoPathChosenLabel = uilabel(app.UIFigure);
            app.WorkingNoPathChosenLabel.Text = 'No Path Chosen';
            app.TRsEditFieldLabel = uilabel(app.UIFigure);
            app.TRsEditFieldLabel.Tooltip = {'Repetition time used in the study, specified in seconds.'};
            app.TRsEditFieldLabel.Text = 'TR (s)';
            app.TRsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TRsEditField.ValueChangedFcn = createCallbackFcn(app, @TRsEditFieldValueChanged, true);
            app.TRsEditField.Tooltip = {'Repetition time used in the study, specified in seconds.'};
            app.VolumestoTrimEditFieldLabel_2 = uilabel(app.UIFigure);
            app.VolumestoTrimEditFieldLabel_2.Tooltip = {'Number of volumes to remove from the beginning of each resting-state fMRI run to allow for MR signal equilibration .'; 'Default: 0'};
            app.VolumestoTrimEditFieldLabel_2.Text = '# Volumes to Trim';
            app.VolumestoTrimEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumestoTrimEditField.ValueChangedFcn = createCallbackFcn(app, @VolumestoTrimEditFieldValueChanged, true);
            app.VolumestoTrimEditField.Tooltip = {'Number of volumes to remove from the beginning of each resting-state fMRI run to allow for MR signal equilibration .'; 'Default: 0'};
            app.UseGSRButtonGroup = uibuttongroup(app.UIFigure);
            app.UseGSRButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UseGSRButtonGroupSelectionChanged, true);
            app.UseGSRButtonGroup.Tooltip = {'(logical)'; 'Denotes whether or not to use global signal regression when performing optimization.'; 'Default: false (no GSR)'};
            app.UseGSRButtonGroup.Title = 'Use GSR?';
            app.YesButton = uiradiobutton(app.UseGSRButtonGroup);
            app.YesButton.Text = 'Yes';
            app.NoButton = uiradiobutton(app.UseGSRButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.RuninGUIButton = uibutton(app.UIFigure, 'push');
            app.RuninGUIButton.ButtonPushedFcn = createCallbackFcn(app, @RuninGUIButtonPushed, true);
            app.RuninGUIButton.Tooltip = {'Run code from UI.'};
            app.RuninGUIButton.Text = 'Run in GUI';
            app.GenerateandDisplayCodeButton = uibutton(app.UIFigure, 'push');
            app.GenerateandDisplayCodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateandDisplayCodeButtonPushed, true);
            app.GenerateandDisplayCodeButton.Tooltip = {'Generate code to be run outside of the UI (i.e. from command window or on a computing cluster).'};
            app.GenerateandDisplayCodeButton.Text = 'Generate and Display Code';
            app.WelcometotheCNAPLabOptimizationToolLabel = uilabel(app.UIFigure);
            app.WelcometotheCNAPLabOptimizationToolLabel.Text = 'Welcome to the Multiband Censoring Optimization Tool (MCOT)';
            app.ContinueFromCrashCheckBox = uicheckbox(app.UIFigure);
            app.ContinueFromCrashCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinueFromCrashCheckBoxValueChanged, true);
            app.ContinueFromCrashCheckBox.Tooltip = {'Searches working directory for intermediate outputs and continues optimization.'};
            app.ContinueFromCrashCheckBox.Text = 'Continue From Crash?';
            app.CodeViewTextAreaLabel = uilabel(app.UIFigure);
            app.CodeViewTextAreaLabel.Text = 'Code View';
            app.CodeViewTextArea = uitextarea(app.UIFigure);
            app.ClearSelectionsandResetGUIButton = uibutton(app.UIFigure, 'push');
            app.ClearSelectionsandResetGUIButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSelectionsandResetGUIButtonPushed, true);
            app.ClearSelectionsandResetGUIButton.Tooltip = {'Clear current selections and reset UI.'};
            app.ClearSelectionsandResetGUIButton.Text = 'Clear Selections and Reset GUI';
            app.InProgressLabel = uilabel(app.UIFigure);
            app.InProgressLabel.Visible = 'off';
            app.InProgressLabel.Text = 'In Progress...';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'cnap logo initials.png';
            app.PleasechoosesettingsrelevanttoyourpreprocessingpipelineLabel = uilabel(app.UIFigure);
            app.PleasechoosesettingsrelevanttoyourpreprocessingpipelineLabel.Text = 'Please choose settings relevant to your preprocessing pipeline.';
            app.BandPassFilteringParametersPanel = uipanel(app.UIFigure);
            app.BandPassFilteringParametersPanel.Title = 'Band-Pass Filtering Parameters';
            app.dBCutoffFrequenciesLabel = uilabel(app.BandPassFilteringParametersPanel);
            app.dBCutoffFrequenciesLabel.Text = '-3 dB Cutoff Frequencies';
            app.LowHzEditFieldLabel = uilabel(app.BandPassFilteringParametersPanel);
            app.LowHzEditFieldLabel.Tooltip = {'Lower -3 dB cutoff frequency for a second-order zero-phase Butterworth Filter, specified in Hz.'};
            app.LowHzEditFieldLabel.Text = 'Low (Hz)';
            app.LowHzEditField = uieditfield(app.BandPassFilteringParametersPanel, 'numeric');
            app.LowHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowHzEditFieldValueChanged, true);
            app.LowHzEditField.Tooltip = {'Lower -3 dB cutoff frequency for a second-order zero-phase Butterworth Filter, specified in Hz.'};
            app.HighHzEditFieldLabel = uilabel(app.BandPassFilteringParametersPanel);
            app.HighHzEditFieldLabel.Tooltip = {'Higher -3 dB cutoff frequency for a second-order zero-phase Butterworth Filter, specified in Hz.'};
            app.HighHzEditFieldLabel.Text = 'High (Hz)';
            app.HighHzEditField = uieditfield(app.BandPassFilteringParametersPanel, 'numeric');
            app.HighHzEditField.ValueChangedFcn = createCallbackFcn(app, @HighHzEditFieldValueChanged, true);
            app.HighHzEditField.Tooltip = {'Higher -3 dB cutoff frequency for a second-order zero-phase Butterworth Filter, specified in Hz.'};
            app.SecondstoTrimPostFilteringLabel = uilabel(app.BandPassFilteringParametersPanel);
            app.SecondstoTrimPostFilteringLabel.Tooltip = {'Number of seconds of data to remove from the beginning and end of each resting-state run to mitigate filter edge effects.'};
            app.SecondstoTrimPostFilteringLabel.Text = {'# Seconds to Trim'; ' Post-Filtering '};
            app.SecondstoTrimPostFilteringEditField = uieditfield(app.BandPassFilteringParametersPanel, 'numeric');
            app.SecondstoTrimPostFilteringEditField.ValueChangedFcn = createCallbackFcn(app, @SecondstoTrimPostFilteringEditFieldValueChanged, true);
            app.SecondstoTrimPostFilteringEditField.Tooltip = {'Number of seconds of data to remove from the beginning and end of each resting-state run to mitigate filter edge effects.'};
            app.UseDefaultFilteringParametersCheckBox = uicheckbox(app.BandPassFilteringParametersPanel);
            app.UseDefaultFilteringParametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDefaultFilteringParametersCheckBoxValueChanged, true);
            app.UseDefaultFilteringParametersCheckBox.Tooltip = {'Load default parameters for second-order zero-phase Butterworth Filter:'; 'Low Cutoff = 0.009 Hz'; 'High Cutoff = 0.08 Hz'; 'Seconds to Trim Post-Filtering = 22 s'};
            app.UseDefaultFilteringParametersCheckBox.Text = 'Use Default Filtering Parameters';
            app.Label = uilabel(app.BandPassFilteringParametersPanel);
            app.Label.Text = '______________________________________';
            app.MinimumRunLengthsEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumRunLengthsEditFieldLabel.Tooltip = {'Minimum requirement for the length of each resting-state run post-volume censoring, specified in seconds.'; 'Default = 120'};
            app.MinimumRunLengthsEditFieldLabel.Text = {'Minimum '; 'Run Length (s)'};
            app.MinimumRunLengthsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumRunLengthsEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumRunLengthsEditFieldValueChanged, true);
            app.MinimumRunLengthsEditField.Tooltip = {'Minimum requirement for the length of each resting-state run post-volume censoring, specified in seconds.'; 'Default = 120'};
            app.UseDefaultCheckBox = uicheckbox(app.UIFigure);
            app.UseDefaultCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDefaultCheckBoxValueChanged, true);
            app.UseDefaultCheckBox.Tooltip = {'Use default minimum seconds of data per resting-state run after volume censoring.'; 'Default = 120'};
            app.UseDefaultCheckBox.Text = 'Use Default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThresholdOptimizer_AlphaUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end